classdef testarduino1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StatusLabel               matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        LampLabel                 matlab.ui.control.Label
        StatusCheckButton         matlab.ui.control.Button
        ConnectionButton          matlab.ui.control.Button
        SerialPortCheckButton     matlab.ui.control.Button
        SerialPortsDropDown       matlab.ui.control.DropDown
        SerialPortsDropDownLabel  matlab.ui.control.Label
        UnoLamp                   matlab.ui.control.Lamp
        UnoLampLabel              matlab.ui.control.Label
        Mega2560Lamp              matlab.ui.control.Lamp
        Mega2560LampLabel         matlab.ui.control.Label
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        COMport % SerialPorts based on the arduino connection condition
        DataBuffer % store the data got from the serialport
        AnimatedLine1 % AnimatedLine for UIAxes (Pressure Sensors)
        AnimatedLine2 % AnimatedLine for UIAxes_2 (GSR Sensors)
        AnimatedLine3 % AnimatedLine for UIAxes_3 (Oximeter Sensor)
        AnimatedLine4 % AnimatedLine for UIAxes_4 (Synchronized Signal)
    end
    
    methods (Access = private)
        
        function serialdatarecieved(app, ~, ~)
            if app.COMport.NumBytesAvailable > 0
                data=readline(app.COMport);
                new_data=split(data, ",");
                values=str2double(new_data);  
                raw_data=values([2,3,4,5,7,8,9]);

                if ~isnan(raw_data)
                    app.DataBuffer=[app.DataBuffer(2:end, :); raw_data'];
                    
                    plot(app.UIAxes, app.DataBuffer(:, 1));
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes, app.DataBuffer(:, 2));
                    hold(app.UIAxes,"off");
                    legend(app.UIAxes,'Left Sensor', 'Right Sensor')
                    drawnow limitrate;
                    %
                    plot(app.UIAxes_2, app.DataBuffer(:, 3));
                    hold(app.UIAxes_2,"on");
                    plot(app.UIAxes_2, app.DataBuffer(:, 4));
                    hold(app.UIAxes_2,"off");
                    legend(app.UIAxes_2,'Combined', "3d Printed")
                    drawnow limitrate;
                    %
                    plot(app.UIAxes_3, app.DataBuffer(:, 5));
                    hold(app.UIAxes_3,"on");
                    plot(app.UIAxes_3, app.DataBuffer(:, 6));
                    hold(app.UIAxes_3,"off");
                    legend(app.UIAxes_3,'LED', 'IR')
                    drawnow limitrate;
                    %
                    plot(app.UIAxes_4, app.DataBuffer(:, 7));
                    legend(app.UIAxes_4,'Synchronized Signal')
                    drawnow limitrate;
                end
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StatusCheckButton
        function StatusCheckButtonPushed(app, event)
            % matbe we need the callback function, but here I didn;t plan
            % to write ir.
            allports=serialportlist("available");
            lamps={app.Mega2560Lamp, app.UnoLamp,app.Lamp};
            for i=1:length(allports)
                portname=allports(i);
                try
                    port=serialport(portname, 500000);% need change
                    pause(1);
                    if  port.NumBytesAvailable > 0
                        lamps{i}.Color='Green';
                    else
                        lamps{i}.Color='Yellow';
                    end
                    clear port;
                catch ME
                    lamps{i}.Color='Red';
                    disp(['无法打开串口 ', portname, ': ', ME.message]);
                end
            end
        end

        % Button pushed function: SerialPortCheckButton
        function SerialPortCheckButtonPushed(app, event)
            avaiableport=serialportlist('available');
            if isempty(avaiableport)
                avaiableport={'No Ports avaiable'};
                app.Label.Text="Check something.";
            else
                app.Label.Text="Choose one port.";
            end
            app.SerialPortsDropDown.Items=avaiableport;
        end

        % Button pushed function: ConnectionButton
        function ConnectionButtonPushed(app, event)
            try
                portname=app.SerialPortsDropDown.Value;
                app.COMport=serialport(portname, 500000);
                configureCallback(app.COMport, "terminator", @app.serialdatarecieved);
                app.DataBuffer=zeros(100,7);
                app.StatusLabel.Text='Port has benn connected';
            catch e
                app.StatusLabel.Text='Port has not benn connected'+e.message;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pressure Sensors')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'GSR Sensors')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Oximeter Sensor (Red and IR)')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Synchronized Signal')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.Mega2560LampLabel = uilabel(app.UIFigure);
            app.Mega2560LampLabel.Text = 'Mega2560';
            app.Mega2560Lamp = uilamp(app.UIFigure);
            app.UnoLampLabel = uilabel(app.UIFigure);
            app.UnoLampLabel.Text = 'Uno';
            app.UnoLamp = uilamp(app.UIFigure);
            app.SerialPortsDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortsDropDownLabel.Text = 'SerialPorts';
            app.SerialPortsDropDown = uidropdown(app.UIFigure);
            app.SerialPortCheckButton = uibutton(app.UIFigure, 'push');
            app.SerialPortCheckButton.ButtonPushedFcn = createCallbackFcn(app, @SerialPortCheckButtonPushed, true);
            app.SerialPortCheckButton.Text = 'SerialPortCheck';
            app.ConnectionButton = uibutton(app.UIFigure, 'push');
            app.ConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectionButtonPushed, true);
            app.ConnectionButton.Text = 'Connection';
            app.StatusCheckButton = uibutton(app.UIFigure, 'push');
            app.StatusCheckButton.ButtonPushedFcn = createCallbackFcn(app, @StatusCheckButtonPushed, true);
            app.StatusCheckButton.Text = 'StatusCheck';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testarduino1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end